classdef chess_reader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MoveSpinnerLabel  matlab.ui.control.Label
        moveEntry         matlab.ui.control.Spinner
        turnLable         matlab.ui.control.Label
        loadButton        matlab.ui.control.Button
        loadLable         matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Start Up - GUI Successfully Initalized")
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [loadfile,path] = uigetfile('../../games/*.txt');
            if loadfile == 0
                disp("Load Button - No file selected.")
                return
            end
            disp(['Load Button - File Name: ', loadfile])
            disp(['Load Button - File Path: ', path])
            app.loadLable.Text = ['Viewing game ', loadfile(5:end-4)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MoveSpinnerLabel = uilabel(app.UIFigure);
            app.MoveSpinnerLabel.Enable = 'off';
            app.MoveSpinnerLabel.Text = 'Move';
            app.moveEntry = uispinner(app.UIFigure);
            app.moveEntry.Limits = [0 Inf];
            app.moveEntry.Enable = 'off';
            app.turnLable = uilabel(app.UIFigure);
            app.turnLable.Enable = 'off';
            app.turnLable.Text = 'To Move';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load Game';
            app.loadLable = uilabel(app.UIFigure);
            app.loadLable.Text = 'Please Select a Game File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = chess_reader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end